classdef ELEC_291_Project_1_Reflow_Oven_Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EditField_4             matlab.ui.control.NumericEditField
        EditField_3             matlab.ui.control.NumericEditField
        EditField_2             matlab.ui.control.NumericEditField
        EditField               matlab.ui.control.NumericEditField
        ReflowTimesSlider       matlab.ui.control.Slider
        TextArea_3              matlab.ui.control.TextArea
        ReflowTimesSliderLabel  matlab.ui.control.Label
        ReflowTempCSlider       matlab.ui.control.Slider
        ReflowTempCSliderLabel  matlab.ui.control.Label
        TextArea_2              matlab.ui.control.TextArea
        SoakingTimesSlider      matlab.ui.control.Slider
        SoakingTimeLabel        matlab.ui.control.Label
        DebugDisplay_Receive    matlab.ui.control.TextArea
        DebugDisplay            matlab.ui.control.TextArea
        SoakingTempCSlider      matlab.ui.control.Slider
        SampleCountLabel        matlab.ui.control.Label
        CLOSEButton             matlab.ui.control.Button
        Lamp                    matlab.ui.control.Lamp
        ProjectLabel            matlab.ui.control.Label
        OPENButton              matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        RefreshButton           matlab.ui.control.Button
        BuadRateEditField       matlab.ui.control.NumericEditField
        BuadRateEditFieldLabel  matlab.ui.control.Label
        COMPortDropDown         matlab.ui.control.DropDown
        COMPortDropDownLabel    matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        serialportopen
        abortflag
        currenttemp
        ovenstatus
        state
    end
    
    methods (Access = private)
        function ClosePortSR(app)
            app.abortflag = 1;
            app.serialportopen = 0;

            app.TextArea.Value = {'No COM port opened.'};
            app.BuadRateEditField.Enable = 'on';
            app.RefreshButton.Enable = 'on';
            app.COMPortDropDown.Enable = 'on';

            app.OPENButton.Visible = 'on';
            app.CLOSEButton.Visible = 'off';

            app.Lamp.Visible = "off";
            app.TextArea_2.Visible = "off";
            app.TextArea_3.Visible = "off";

            app.COMPortDropDown.Items = serialportlist("available");
            app.COMPortDropDown.Items(end+1) = {'NONE'};
        end

        function OpenPortSR(app)
            app.TextArea.Value = {'COM port sucessfully opened.'};
            app.BuadRateEditField.Enable = 'off';
            app.RefreshButton.Enable = 'off';
            app.COMPortDropDown.Enable = 'off';

            app.OPENButton.Visible = 'off';
            app.CLOSEButton.Visible = 'on';

            app.Lamp.Visible = "on";
            app.TextArea_2.Visible = "on";
            app.TextArea_3.Visible = "on";
        end

        function StartDataLog(app)
            app.abortflag = 0;
            a = plot(app.UIAxes,1,1);
            delete(a);
            if app.serialportopen ~= 0
                flush(app.serialportopen);
                x = 1:1000000;
                y = 0*(1:1000000);
                hLine = plot(app.UIAxes,x,y);
                for i=1:1000000
                    if app.abortflag == 1
                        break;
                    end
                    flush(app.serialportopen);
                    str = read(app.serialportopen, 9, "uint8");
                    app.DebugDisplay_Receive.Value = char(str);
                    y(i) = (str(1)*256^3+str(2)*256^2+str(3)*256+str(4))/100;
                    app.TextArea_3.Value = [num2str(y(i)), ' °C'];
                    app.EditField.Value = double(bitand(str(5),0x0F)) + 10 * double(bitand(str(5),0xF0)) / 16 + 100;
                    app.SoakingTempCSlider.Value = app.EditField.Value;
                    app.EditField_2.Value = double(bitand(str(6),0x0F)) + 10 * double(bitand(str(6),0xF0)) / 16;
                    app.SoakingTimesSlider.Value = app.EditField_2.Value;
                    app.EditField_3.Value = double(bitand(str(7),0x0F)) + 10 * double(bitand(str(7),0xF0)) / 16 + 200;
                    app.ReflowTempCSlider.Value = app.EditField_3.Value;
                    app.EditField_4.Value = double(bitand(str(8),0x0F)) + 10 * double(bitand(str(8),0xF0)) / 16;
                    app.ReflowTimesSlider.Value = app.EditField_4.Value;
                    app.TextArea_2.Value = app.state(double(bitand(str(9),0x0F)) + 1);
                    if double(bitand(str(9),0x0F)) > 0
                        app.Lamp.Color = "Green";
                    else
                        app.Lamp.Color = "Black";
                    end
                    stripchart('Update',hLine,y(i));
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.COMPortDropDown.Items = serialportlist("available");
            app.COMPortDropDown.Items(end+1) = {'NONE'};
            app.ovenstatus = '0';
            app.state = ["Standby", "Heating", "Soaking", "Reflow", "Cooldown"];
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.COMPortDropDown.Items = serialportlist("available");
            app.COMPortDropDown.Items(end+1) = {'NONE'};
        end

        % Button pushed function: OPENButton
        function OPENButtonPushed(app, event)
            if (strfind(app.COMPortDropDown.Value,'COM') ~= 0)
                if (app.BuadRateEditField.Value > 0)
                    app.serialportopen = serialport(app.COMPortDropDown.Value,app.BuadRateEditField.Value);
                    OpenPortSR(app);
                    StartDataLog(app);
                else
                    app.TextArea.Value = {'Baud rate cannot be negative.'};
                end
            else    
                app.TextArea.Value = {'No valid COM Port found.'};
            end
            
        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            app.abortflag = 1;
            ClosePortSR(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Temperature Plot')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Temperature (°C)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [999500 1000000];
            app.UIAxes.XTickLabel = {'-500'; '-450'; '-400'; '-350'; '-300'; '-250'; '-200'; '-150'; '-100'; '-50'; '0'};
            app.COMPortDropDownLabel = uilabel(app.UIFigure);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.UIFigure);
            app.COMPortDropDown.Items = {''};
            app.COMPortDropDown.Value = '';
            app.BuadRateEditFieldLabel = uilabel(app.UIFigure);
            app.BuadRateEditFieldLabel.Text = 'Buad Rate';
            app.BuadRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BuadRateEditField.ValueDisplayFormat = '%11.4d';
            app.BuadRateEditField.Value = 115200;
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'No COM port opened.'};
            app.OPENButton = uibutton(app.UIFigure, 'push');
            app.OPENButton.ButtonPushedFcn = createCallbackFcn(app, @OPENButtonPushed, true);
            app.OPENButton.Text = 'OPEN';
            app.ProjectLabel = uilabel(app.UIFigure);
            app.ProjectLabel.Text = {'ELEC 291 - Electrical Engineering Design Studio I'; 'Project 1 - Reflow Oven Controller'; 'Group B7 - 2024.'};
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Visible = 'off';
            app.CLOSEButton = uibutton(app.UIFigure, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Visible = 'off';
            app.CLOSEButton.Text = 'CLOSE';
            app.SampleCountLabel = uilabel(app.UIFigure);
            app.SampleCountLabel.Text = {'Soaking'; 'Temp (°C)'};
            app.SoakingTempCSlider = uislider(app.UIFigure);
            app.SoakingTempCSlider.Limits = [100 199];
            app.SoakingTempCSlider.Enable = 'off';
            app.SoakingTempCSlider.Value = 160;
            app.DebugDisplay = uitextarea(app.UIFigure);
            app.DebugDisplay.Editable = 'off';
            app.DebugDisplay.Visible = 'off';
            app.DebugDisplay_Receive = uitextarea(app.UIFigure);
            app.DebugDisplay_Receive.Editable = 'off';
            app.DebugDisplay_Receive.Visible = 'off';
            app.SoakingTimeLabel = uilabel(app.UIFigure);
            app.SoakingTimeLabel.Text = {'Soaking'; 'Time (s)'};
            app.SoakingTimesSlider = uislider(app.UIFigure);
            app.SoakingTimesSlider.Limits = [0 99];
            app.SoakingTimesSlider.Enable = 'off';
            app.SoakingTimesSlider.Value = 70;
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Visible = 'off';
            app.ReflowTempCSliderLabel = uilabel(app.UIFigure);
            app.ReflowTempCSliderLabel.Text = {'Reflow'; 'Temp (°C)'};
            app.ReflowTempCSlider = uislider(app.UIFigure);
            app.ReflowTempCSlider.Limits = [200 240];
            app.ReflowTempCSlider.Enable = 'off';
            app.ReflowTempCSlider.Value = 220;
            app.ReflowTimesSliderLabel = uilabel(app.UIFigure);
            app.ReflowTimesSliderLabel.Text = {'Reflow'; 'Time (s)'};
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Visible = 'off';
            app.ReflowTimesSlider = uislider(app.UIFigure);
            app.ReflowTimesSlider.Limits = [0 60];
            app.ReflowTimesSlider.Enable = 'off';
            app.ReflowTimesSlider.Value = 30;
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField.Value = 160;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 70;
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = 220;
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ELEC_291_Project_1_Reflow_Oven_Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end